classdef NRO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveMatrixButton              matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        OutputsPanel                  matlab.ui.container.Panel
        OutputLabel                   matlab.ui.control.Label
        OutputLabel_2                 matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        InputsPanel                   matlab.ui.container.Panel
        NodeParametersLabel           matlab.ui.control.Label
        LinkParametersLabel           matlab.ui.control.Label
        RepairRateEditField_2         matlab.ui.control.EditField
        RepairRateEditField_2Label    matlab.ui.control.Label
        FailureRateEditField_2        matlab.ui.control.EditField
        FailureRateEditField_2Label   matlab.ui.control.Label
        AddalinkbetweenLabel          matlab.ui.control.Label
        Node2EditField                matlab.ui.control.EditField
        Node2EditFieldLabel           matlab.ui.control.Label
        Node1EditField                matlab.ui.control.EditField
        Node1EditFieldLabel           matlab.ui.control.Label
        CalculatetheReliabilityofInitialgraphCheckBox  matlab.ui.control.CheckBox
        RepairRateEditField           matlab.ui.control.EditField
        RepairRateEditFieldLabel      matlab.ui.control.Label
        FailureRateEditField          matlab.ui.control.EditField
        FailureRateEditFieldLabel     matlab.ui.control.Label
        DeltatEditField               matlab.ui.control.EditField
        DeltatEditFieldLabel          matlab.ui.control.Label
        TimemonthsEditField           matlab.ui.control.EditField
        TimemonthsEditFieldLabel      matlab.ui.control.Label
        MethodSelectionDropDown       matlab.ui.control.DropDown
        MethodSelectionDropDownLabel  matlab.ui.control.Label
        GraphPanel                    matlab.ui.container.Panel
        ShowClustersButton            matlab.ui.control.Button
        FetchitButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        OriginalAdjacencyMatrix % original adjacency matrix
        AdjacencyMatrix % Description
        NewAdjMatrix
    end
    
    
    methods (Access = private)
        
        function SavethetopologyButtonPushed(app, event)
            newAdjMatrix = app.NewAdjMatrix;

            % Prompt the user to select a location and filename to save the matrix
            [file, path] = uiputfile('*.mat', 'Save New Adjacency Matrix As');

            % If the user does not cancel the dialog, save the file
            if ischar(file)
                save(fullfile(path, file), 'newAdjMatrix');
                uialert(app.UIFigure, 'The new adjacency matrix has been saved successfully.', 'Save Successful');
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FetchitButton
        function FetchitButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select a MAT file containing the adjacency matrix');
            if isequal(file, 0) || isequal(path, 0)
                uialert(app.UIFigure, 'No file was selected', 'File Selection');
                return;
            end
            S = load(fullfile(path, file));
            
            if isfield(S, 'cities_network')
                app.AdjacencyMatrix = S.cities_network;
                app.OriginalAdjacencyMatrix = S.cities_network;
                G = graph(app.AdjacencyMatrix, 'lower');
                plot(app.UIAxes, G);
                title(app.UIAxes, 'Network Graph');
                xlabel(app.UIAxes, 'Node Index');
                ylabel(app.UIAxes, 'Node Index');
                axis(app.UIAxes, 'equal');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                                
                uialert(app.UIFigure, 'Network Graph loaded successfully!', 'Success',"Icon","success");
            else
                uialert(app.UIFigure, 'The selected file does not contain a variable named ''cities_network''.', 'Error');
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cla(app.UIAxes2, 'reset');
            method = app.MethodSelectionDropDown.Value;
            time = str2double(app.TimemonthsEditField.Value);
            deltat = str2double(app.DeltatEditField.Value);
            step = time/deltat;
            seed = 1;
            lambda_link = eval(app.FailureRateEditField.Value);
            mu_link = str2double(app.RepairRateEditField.Value);
            node1 = str2double(app.Node1EditField.Value);
            node2 = str2double(app.Node2EditField.Value);
            lambda_node = eval(app.FailureRateEditField_2.Value);
            mu_node = str2double(app.RepairRateEditField_2.Value);
            app.AdjacencyMatrix = app.OriginalAdjacencyMatrix;
            originalGraph = graph(app.AdjacencyMatrix);
            %step = 1e6;
            % if deltat * max(mu_node, mu_link) >= 0.1
            %     errordlg({'The product of Delta(t) and maximum repair rate must be less than 0.1.'}, 'Validation Error');
            %     app.DeltatEditField.Value = ''; 
            % 
            %     return; 
            % end
            nodePositions = extractNodePositions(originalGraph, app.UIAxes);
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
                'Message', 'Simulating... 0%', 'Indeterminate', 'off');
            d.Value = 0;

            try
                disp('Starting simulation...');
                disp(['Time: ', num2str(time)]);
                disp(['Step: ', num2str(step)]);
                disp(['Seed: ', num2str(seed)]);
                disp(['Lambda Link: ', num2str(lambda_link)]);
                disp(['Mu Link: ', num2str(mu_link)]);
                disp(['Lambda Node: ', num2str(lambda_node)]);
                disp(['Mu Node: ', num2str(mu_node)]);


                if app.CalculatetheReliabilityofInitialgraphCheckBox.Value
                    initialReliability = Simulator_linkandnodefailure_rrt(app.OriginalAdjacencyMatrix, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                    app.OutputLabel.Text = sprintf('Initial Reliability Value: %.2f%%', initialReliability * 100);
                else
                    app.OutputLabel.Text = '';  
                end


                switch method
                    case 'Brute Force'
                        tic;
                        [x, y, rel, AM] = bruteforceSol(app.AdjacencyMatrix, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        newGraph = graph(AM);
                        plotGraphO(x,y,app.UIAxes2, newGraph, nodePositions,'Brute Force - Best Link');
                        app.OutputLabel_2.Text = sprintf('Brute Force - Best Link: (%d, %d) with Reliability: %.2f%%\nTime Elapsed: %.2f seconds', x, y, rel*100, elapsedTime);
                        newAdjMatrix = AM;

                    case 'K-means Clustering'
                        tic;
                        [cluster_idx, C] = clusterKmeans(app.AdjacencyMatrix);
                        disp('K-means clustering completed.');
                        [x, y, rel, AM] = clusterSol(app.AdjacencyMatrix, cluster_idx, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        plotClusterGraph(x,y,app.UIAxes2, AM,cluster_idx ,nodePositions, 'K-means Clustering');
                        app.OutputLabel_2.Text = sprintf('K-means Clustering - Best Link: (%d, %d)\nReliability: %.2f%%\nTime Elapsed: %.2f seconds', x, y, rel * 100, elapsedTime);
                        newAdjMatrix = AM;

                    case 'Degree'
                        tic;
                        [x, y, rel, AM] = degreeSol(app.AdjacencyMatrix, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        newGraph = graph(AM);
                        plotGraphO(x,y,app.UIAxes2, newGraph, nodePositions , 'Degree');
                        app.OutputLabel_2.Text = sprintf('Degree - Best Link: (%d, %d) with Reliability: %.2f%%\nTime Elapsed: %.2f seconds', x, y, rel * 100, elapsedTime);
                        newAdjMatrix = AM;

                    case 'Degree with Clustering'
                        tic;
                        [cluster_idx, C] = clusterKmeans(app.AdjacencyMatrix);
                        [x, y, rel, AM] = degreeClusterSol(app.AdjacencyMatrix, cluster_idx, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        plotClusterGraph(x,y,app.UIAxes2, AM,cluster_idx ,nodePositions, 'Degree with Clustering');
                        app.OutputLabel_2.Text = sprintf('Degree with Clustering - Best Link: (%d, %d)\nReliability: %.2f%%\nTime Elapsed: %.2f seconds', x, y, rel * 100, elapsedTime);
                        newAdjMatrix = AM;

                    case 'Diameter'
                        tic;
                        [x, y, oldDiameter, newDiameter, rel, AM] = diameterSol(app.AdjacencyMatrix, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        newGraph = graph(AM);
                        plotGraphO(x,y,app.UIAxes2, newGraph, nodePositions, 'Diameter Method');
                        app.OutputLabel_2.Text = sprintf('Diameter Method - Best Link: (%d, %d)\nOld Diameter: %.2f , New Diameter: %.2f\nReliability: %.2f%%\nTime Elapsed: %.2f seconds', x, y, oldDiameter, newDiameter, rel * 100, elapsedTime);
                        newAdjMatrix = AM;

                    case 'Adding Link Manually'
                        tic;
                        modifiedMatrix = modifymat(app.AdjacencyMatrix, node1, node2);
                        newGraph = graph(modifiedMatrix);
                        plotGraphO(node1,node2,app.UIAxes2, newGraph, nodePositions, 'Manually Added Link');
                        Reliability = Simulator_linkandnodefailure_rrt(modifiedMatrix, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        app.OutputLabel_2.Text = sprintf('New Reliability Value: %.2f%%\nTime Elapsed: %.2f seconds', Reliability * 100, elapsedTime);
                        newAdjMatrix = modifiedMatrix;
                    case 'Remove Link Manually'
                        tic;
                        currentMatrix = app.AdjacencyMatrix;  
                        modifiedMatrix = removelink(currentMatrix, node1, node2);
                        newGraph = graph(modifiedMatrix);
                        plotGraphO_removededge(node1, node2, app.UIAxes2, newGraph, nodePositions, 'Manually Removed Link');
                        Reliability = Simulator_linkandnodefailure_rrt(modifiedMatrix, time, step, seed, lambda_link, mu_link, lambda_node, mu_node);
                        elapsedTime = toc;
                        app.OutputLabel_2.Text = sprintf('New Reliability Value: %.2f%%\nTime Elapsed: %.2f seconds', Reliability * 100, elapsedTime);
                        newAdjMatrix = modifiedMatrix;


                end

               app.NewAdjMatrix = newAdjMatrix;
            
                    for progress = 1:10
                        pause(0.1); 
                        d.Value = progress / 10;
                        d.Message = sprintf('Simulating... %d%%', progress * 10);
                    end
            
                    disp('Processing completed.');
                    d.Value = 1;
                    d.Message = 'Simulation complete!';
                    pause(1);
                    close(d);
            
                catch ME
                    close(d);
                    uialert(app.UIFigure, ['An error occurred: ', ME.message], 'Error');
                    disp(['An error occurred: ', ME.message]);
            end
               
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: ShowClustersButton
        function ShowClustersButtonPushed(app, event)
            [cluster_idx, C] = clusterKmeans(app.AdjacencyMatrix);
            G = graph(app.AdjacencyMatrix);
            plot(app.UIAxes, G);
            hold(app.UIAxes, 'on'); 
            p = plot(app.UIAxes, G);
            p.NodeCData = cluster_idx;
            colormap(app.UIAxes, jet(2));
            hold(app.UIAxes, 'off');  
       
        end

        % Value changed function: MethodSelectionDropDown
        function MethodSelectionDropDownValueChanged(app, event)
            value = app.MethodSelectionDropDown.Value;
            app.Node1EditField.Visible = 'off';
            app.Node1EditFieldLabel.Visible = 'off';
            app.Node2EditField.Visible = 'off';
            app.Node2EditFieldLabel.Visible = 'off';
            app.FailureRateEditFieldLabel.Visible = 'off';
            app.FailureRateEditField.Visible = 'off';
            app.FailureRateEditFieldLabel.Visible = 'off';
            app.RepairRateEditField.Visible = 'off';
            app.RepairRateEditFieldLabel.Visible = 'off';
            %app.SeedEditField.Visible = 'off';
            %app.SeedEditFieldLabel.Visible = 'off';
            app.DeltatEditField.Visible = 'off';
            app.DeltatEditFieldLabel.Visible = 'off';
            app.TimemonthsEditField.Visible = 'off';
            app.TimemonthsEditFieldLabel.Visible = 'off';
            app.AddalinkbetweenLabel.Visible = 'off';
            app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'off';

        
            % Then, only turn on the necessary ones
            switch value
                case 'Brute Force'
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';


                case 'K-means Clustering'
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';


                case 'Hierarchical Clustering'
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';

                case 'Degree'
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';

                case 'Degree with Clustering'
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';

                case 'Diameter'  
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';
                    
                case 'Adding Link Manually'
                    app.AddalinkbetweenLabel.Text = 'Add a link between:';
                    app.AddalinkbetweenLabel.Visible = 'on';
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.Node1EditField.Visible = 'on';
                    app.Node1EditFieldLabel.Visible = 'on';
                    app.Node2EditField.Visible = 'on';
                    app.Node2EditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';

                case 'Remove Link Manually'
                    app.AddalinkbetweenLabel.Text = 'Remove a link between:';
                    app.AddalinkbetweenLabel.Visible = 'on';
                    app.TimemonthsEditField.Visible = 'on';
                    app.TimemonthsEditFieldLabel.Visible = 'on';
                    app.DeltatEditField.Visible = 'on';
                    app.DeltatEditFieldLabel.Visible = 'on';
                    %app.SeedEditField.Visible = 'on';
                    %app.SeedEditFieldLabel.Visible = 'on';
                    app.FailureRateEditField.Visible = 'on';
                    app.FailureRateEditFieldLabel.Visible = 'on';
                    app.RepairRateEditField.Visible = 'on';
                    app.RepairRateEditFieldLabel.Visible = 'on';
                    app.Node1EditField.Visible = 'on';
                    app.Node1EditFieldLabel.Visible = 'on';
                    app.Node2EditField.Visible = 'on';
                    app.Node2EditFieldLabel.Visible = 'on';
                    app.CalculatetheReliabilityofInitialgraphCheckBox.Visible = 'on';

                otherwise
                    % Do nothing (all are off by default)
            end
        end

        % Button pushed function: SaveMatrixButton
        function SaveMatrixButtonPushed(app, event)
            newAdjMatrix = app.NewAdjMatrix;

            disp('New Adjacency Matrix:');
            disp(newAdjMatrix);
            if isempty(newAdjMatrix)
                uialert(app.UIFigure, 'The adjacency matrix is empty. Please ensure it has been properly computed.', 'Error');
                return;
            end

            [file, path] = uiputfile('*.mat', 'Save New Adjacency Matrix As');
            disp(['Selected file: ', fullfile(path, file)]);
            if ischar(file)
                try
                    cities_network = newAdjMatrix; % Assign the matrix to the correct variable name
                    save(fullfile(path, file), 'cities_network'); % Save the matrix with the correct variable name
                    uialert(app.UIFigure, 'The new adjacency matrix has been saved successfully.', 'Save Successful',"Icon","success");
                catch ME
                    uialert(app.UIFigure, ['Failed to save the file. Error: ', ME.message], 'Save Failed');
                end
            else
                disp('File save cancelled.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph Panel';
            app.FetchitButton = uibutton(app.GraphPanel, 'push');
            app.FetchitButton.ButtonPushedFcn = createCallbackFcn(app, @FetchitButtonPushed, true);
            app.FetchitButton.Text = 'Fetch it !';
            app.ShowClustersButton = uibutton(app.GraphPanel, 'push');
            app.ShowClustersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowClustersButtonPushed, true);
            app.ShowClustersButton.Text = 'Show Clusters';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs Panel';
            app.MethodSelectionDropDownLabel = uilabel(app.InputsPanel);
            app.MethodSelectionDropDownLabel.Text = 'Method Selection';
            app.MethodSelectionDropDown = uidropdown(app.InputsPanel);
            app.MethodSelectionDropDown.Items = {'Brute Force', 'K-means Clustering', 'Degree', 'Degree with Clustering', 'Diameter', 'Adding Link Manually', 'Remove Link Manually'};
            app.MethodSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodSelectionDropDownValueChanged, true);
            app.MethodSelectionDropDown.Value = 'Brute Force';
            app.TimemonthsEditFieldLabel = uilabel(app.InputsPanel);
            app.TimemonthsEditFieldLabel.Text = 'Time [months]';
            app.TimemonthsEditField = uieditfield(app.InputsPanel, 'text');
            app.TimemonthsEditField.Value = '300';
            app.DeltatEditFieldLabel = uilabel(app.InputsPanel);
            app.DeltatEditFieldLabel.Interpreter = 'latex';
            app.DeltatEditFieldLabel.Text = '\Delta t';
            app.DeltatEditField = uieditfield(app.InputsPanel, 'text');
            app.DeltatEditField.Value = '0.05';
            app.FailureRateEditFieldLabel = uilabel(app.InputsPanel);
            app.FailureRateEditFieldLabel.Text = 'Failure Rate';
            app.FailureRateEditField = uieditfield(app.InputsPanel, 'text');
            app.FailureRateEditField.Value = '1/25';
            app.RepairRateEditFieldLabel = uilabel(app.InputsPanel);
            app.RepairRateEditFieldLabel.Text = 'Repair Rate';
            app.RepairRateEditField = uieditfield(app.InputsPanel, 'text');
            app.RepairRateEditField.Value = '2';
            app.CalculatetheReliabilityofInitialgraphCheckBox = uicheckbox(app.InputsPanel);
            app.CalculatetheReliabilityofInitialgraphCheckBox.Text = 'Calculate the Reliability of Initial graph';
            app.Node1EditFieldLabel = uilabel(app.InputsPanel);
            app.Node1EditFieldLabel.Visible = 'off';
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.Node1EditField = uieditfield(app.InputsPanel, 'text');
            app.Node1EditField.Visible = 'off';
            app.Node2EditFieldLabel = uilabel(app.InputsPanel);
            app.Node2EditFieldLabel.Visible = 'off';
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node2EditField = uieditfield(app.InputsPanel, 'text');
            app.Node2EditField.Visible = 'off';
            app.AddalinkbetweenLabel = uilabel(app.InputsPanel);
            app.AddalinkbetweenLabel.Visible = 'off';
            app.AddalinkbetweenLabel.Text = 'Add a link between:';
            app.FailureRateEditField_2Label = uilabel(app.InputsPanel);
            app.FailureRateEditField_2Label.Text = 'Failure Rate';
            app.FailureRateEditField_2 = uieditfield(app.InputsPanel, 'text');
            app.FailureRateEditField_2.Value = '1/50';
            app.RepairRateEditField_2Label = uilabel(app.InputsPanel);
            app.RepairRateEditField_2Label.Text = 'Repair Rate';
            app.RepairRateEditField_2 = uieditfield(app.InputsPanel, 'text');
            app.RepairRateEditField_2.Value = '1';
            app.LinkParametersLabel = uilabel(app.InputsPanel);
            app.LinkParametersLabel.Text = 'Link Parameters:';
            app.NodeParametersLabel = uilabel(app.InputsPanel);
            app.NodeParametersLabel.Text = 'Node Parameters:';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs Panel';
            app.UIAxes = uiaxes(app.OutputsPanel);
            title(app.UIAxes, 'Initial Topology')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.OutputsPanel);
            title(app.UIAxes2, 'New Topology')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OutputLabel_2 = uilabel(app.OutputsPanel);
            app.OutputLabel_2.Text = 'Output ';
            app.OutputLabel = uilabel(app.OutputsPanel);
            app.OutputLabel.Text = 'Output';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveMatrixButton = uibutton(app.UIFigure, 'push');
            app.SaveMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatrixButtonPushed, true);
            app.SaveMatrixButton.Icon = 'dwl.png';
            app.SaveMatrixButton.Text = 'Save Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NRO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end